classdef CollisionPrediction < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        CollisionwarningLamp       matlab.ui.control.Lamp
        CollisionwarningLampLabel  matlab.ui.control.Label
        StartButton                matlab.ui.control.Button
        UIAxes                     matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        prePreviousObjects
        previousObjects % Variable to store previous objects
        currentObjects  % Variable to store current objects
        imWidth
        imHeight
        orderPolynomial = 1
        paths %Save up data about the predicted paths
    end
    
    methods (Access = public)
        
        function [colorObject] = getColour(~,object)
            typeObject = object(1);
            if(typeObject==0)
                colorObject = 'magenta';
            elseif(typeObject==1)  
                colorObject = 'w';
            elseif(typeObject==2)
                colorObject = 'c';
            elseif(typeObject==3)
                colorObject = 'b';
            elseif(typeObject==4)
                colorObject = 'k';
            elseif(typeObject==5) 
                colorObject = 'y';
            end
        end
        
        function [] = showImage(app,name)
            image = imread(append(name,".jpg"));
            imshow(image,'Parent',app.UIAxes);
            imageHeight = length(image(:,1,1));
            imageWidth  = length(image(1,:,1));
            app.imHeight = imageHeight;
            app.imWidth = imageWidth;
            limit = size(app.currentObjects);
            for i=1:limit(1)
                x = app.currentObjects(i,2)*imageWidth-app.currentObjects(i,4)*imageWidth/2.0;
                y = app.currentObjects(i,3)*imageHeight-app.currentObjects(i,5)*imageHeight/2.0;
                w = app.currentObjects(i,4)*imageWidth;
                h = app.currentObjects(i,5)*imageHeight;
                colorObject = getColour(app,app.currentObjects(i,1));
                images.roi.Rectangle('Parent', app.UIAxes,'Position',[x,y,w,h],'EdgeColor',colorObject,'LabelVisible','inside');
            end
        end

        function [name,num] = getName(app,i)
%             if(i<10)
%                 %num = append('0000',int2str(i));
%                 num = append('00',int2str(i));
%             elseif(i<100)
%                 %num = append('000',int2str(i));
%                 num = append('0',int2str(i));
%             else
%                 %num = append('00',int2str(i));
%                 num = append('',int2str(i));
%             end
% % %             %name = append("Imatge",num);
%             name = append("C:\ImatgesDavid01\ezgif-frame-",num);
            num  = mat2str(i);
            name = append("C:\Users\rcoll\Desktop\TFG\darknet-master\data\images\",num);
        end

        function [isInRange] = whithinRange(app,current,previous)
            % Here the parameters for what the same object is considered to
            % be are laid out. In this case, if one object is whithin the
            % ROI of the previous one, it is considered to be the same
            % object
            offset = 1;
            if(current(1)==2)
                % As people(class 2) move fast relative to their ROI, this
                %offset is set to facilitate detection
                offset=2;
            end
            if(current(2)>=((previous(2)-previous(4)/2*offset)) && current(2)<=((previous(2)+previous(4)/2*offset)))
                if(current(3)>=((previous(3)-previous(5)/2*offset)) && current(3)<=((previous(3)+previous(5)/2*offset)))
                    isInRange=true;
                    return
                end
            end
            isInRange = false;
        end

        function [previous] = findprePrevious(app,current)
            limit = size(app.prePreviousObjects);
            for i=1:limit(1)
                % Check for previous objects of the same class
                if(app.prePreviousObjects(i,1)==current(1))
                    % If whithin range, consider same object
                    if(whithinRange(app,current,app.prePreviousObjects(i,:)))
                        previous = app.prePreviousObjects(i,:);
                        return
                    end
                end
            end
            previous=[];
        end


        function [previous] = findPrevious(app,current)
            limit = size(app.previousObjects);
            for i=1:limit(1)
                % Check for previous objects of the same class
                if(app.previousObjects(i,1)==current(1))
                    % If whithin range, consider same object
                    if(whithinRange(app,current,app.previousObjects(i,:)))
                        previous = app.previousObjects(i,:);
                        return
                    end
                end
            end
            previous=[];
        end

        function [] = findPath(app)
            limit = size(app.currentObjects);
            for i=1:limit(1)
                previous  = findPrevious(app,app.currentObjects(i,:));
                % If the object appears in the previous image
                if(~isempty(previous))
                    xNow = app.currentObjects(i,2)*app.imWidth;
                    yNow = app.currentObjects(i,3)*app.imHeight;
                    xBefore = previous(2)*app.imWidth;
                    yBefore = previous(3)*app.imHeight;

                    % Try to find object two frames before
                    prePrevious = findprePrevious(app,previous);
                    if(~isempty(prePrevious))
                        x2Before = prePrevious(2)*app.imWidth;
                        y2Before = prePrevious(3)*app.imHeight;
                        % Linear regression of the three points
                        p = polyfit([xNow xBefore x2Before],[yNow yBefore y2Before],1);
                        xSpeed = (xNow - xBefore);
                        ySpeed = (yNow - yBefore);
                    else 
                        % Polynomial fit of the two points
                        p = polyfit([xNow xBefore],[yNow yBefore],1);
                        xSpeed = (xNow - xBefore);
                        ySpeed = (yNow - yBefore);
                    end

                    % To avoid predicting paths of obects that are not
                    % moving. 
                    speedOffset = 0.1;
                    if(abs(xSpeed)>(speedOffset*app.currentObjects(i,4)*app.imWidth)|| abs(ySpeed)>(speedOffset*app.currentObjects(i,5)*app.imHeight))
                        x1 = linspace(xNow-app.currentObjects(i,4)*app.imWidth,xNow+app.currentObjects(i,4)*app.imWidth);
                        y1 = polyval(p,x1);
                        hold(app.UIAxes);
                        color = getColour(app,app.currentObjects(i,:));
                        plot(app.UIAxes,x1,y1,color,'LineWidth',2);
                        ylim(app.UIAxes,[0 app.imHeight]);
                        xlim(app.UIAxes,[0 app.imWidth]);
                        hold(app.UIAxes);
                        % pahts(i) is a variable to store the positions and
                        % speed, as well as the curve of the predicted
                        % path. used if to disregard previous paths
                        app.paths(i).xspeed=xSpeed;
                        app.paths(i).yspeed=ySpeed;
                        app.paths(i).parameters = p;
                        app.paths(i).xposition = xNow;
                        app.paths(i).yposition = yNow;
                        app.paths(i).used = true;
                        app.paths(i).class = app.currentObjects(i,1);
                    else
                        app.paths(i).used = false;
                    end
                else
                    app.paths(i).used = false;
                end
            end
        end

        function [xout,yout] = drawLine(app,x,path)
            if(x>path.xposition)
                xout = linspace(path.xposition,x);
            else
                xout = linspace(x,path.xposition);
            end
            yout = zeros(1,length(xout));
            for i=1:length(xout)
                yout(i)=path.parameters(2)+path.parameters(1)*xout(i);
            end
        end

        function [] = collisionDetected(app,x,y,path1,path2)
            % Warn of a predicted collision being detected
            app.CollisionwarningLamp.Color = [1 0 0];
            hold(app.UIAxes);
            plot(app.UIAxes,x,y,'*','LineWidth',4,'Color','r')
            hold(app.UIAxes);
            [x1,y1] = drawLine(app,x,path1);
            [x2,y2] = drawLine(app,x,path2);
            hold(app.UIAxes);
            plot(app.UIAxes,x1,y1,'LineWidth',4,'Color','r')
            hold(app.UIAxes);
            hold(app.UIAxes);
            plot(app.UIAxes,x2,y2,'LineWidth',4,'Color','r')
            hold(app.UIAxes);

        end

        function [collision,xcollision,ycollision] = pathCollision(app,path1,path2)
            try
                syms x y
                eqn1 = -path1.parameters(1)*x + y == path1.parameters(2);
                eqn2 = -path2.parameters(1)*x + y == path2.parameters(2);
                sol = solve([eqn1, eqn2], [x, y]);
                xcollision = sol.x;
                ycollision = sol.y;

                if(~isnan(xcollision) && ~isnan(ycollision))
                    collision = true;
                    return
                end
            catch 
            end
            collision = false;
        end


        function [] = detectCollisions(app)
            for i=1:length(app.paths)-1
                if(app.paths(i).used)
                    for j=i+1:length(app.paths)
                        if(app.paths(j).used)
                            [intersection,x,y] = pathCollision(app,app.paths(i),app.paths(j));
                            % Restrict collisions to the camera view only
                            if(x>0 && x<app.imWidth && y>0 && y<app.imHeight && intersection)
                                % Check if both objects direction is
                                %towards the intersection point
                                if (sign(app.paths(i).xposition-x)~=sign(app.paths(i).xspeed) && ...
                                    sign(app.paths(j).xposition-x)~=sign(app.paths(j).xspeed) && ...
                                    sign(app.paths(i).yposition-y)~=sign(app.paths(i).yspeed) && ...
                                    sign(app.paths(j).yposition-y)~=sign(app.paths(j).yspeed) )
                                    % Not consider person to person collisions
                                    if (not(app.paths(i).class==app.paths(j).class && app.paths(j).class==2))
                                        collisionDetected(app,x,y,app.paths(i),app.paths(j));
                                    end
                                end
                            end
                        end
                    end
                end
            end
        end
        
        function [] = collisionDetection(app)
            init  = 8790;
            final = 9200;
%             time = zeros(final-init,1);
%             n=1;
%             name = getName(app,init);
%             showImage(app,name);
            for i=init:final
%                 ti = tic;
                [name,num] = getName(app,i);
                app.currentObjects = table2array(readtable(append(name,'.txt')));
                showImage(app,name);
                if(~isempty(app.previousObjects))
                    findPath(app);
                    detectCollisions(app);
                end
                app.prePreviousObjects = app.previousObjects;
                app.previousObjects = app.currentObjects;
                app.paths = [];
%                 pause(1);
                app.CollisionwarningLamp.Color = [0 1 0];
                imwrite(frame2im(getframe(app.UIAxes)), append('video-',num,'.jpg'))
%                 time(n) = toc(ti);
%                 n=n+1;
            end
%            assignin('base','timeNoShowing',time)
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            app.StartButton.Visible = "off";
            collisionDetection(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.CollisionwarningLampLabel = uilabel(app.UIFigure);
            app.CollisionwarningLampLabel.Text = 'Collision warning';
            app.CollisionwarningLamp = uilamp(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CollisionPrediction

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end