classdef Computer_Vision_Dataset_Generator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        NothingButton                matlab.ui.control.Button
        CurrentImageLabel            matlab.ui.control.Label
        Label                        matlab.ui.control.Label
        AddRegionofInterestROILabel  matlab.ui.control.Label
        AddBusButton                 matlab.ui.control.Button
        AddMotorbikeButton           matlab.ui.control.Button
        AddTruckButton               matlab.ui.control.Button
        AddBicycleButton             matlab.ui.control.Button
        AddPedestrianButton          matlab.ui.control.Button
        Image                        matlab.ui.control.Image
        PreviousImageButton          matlab.ui.control.Button
        DatatypeDropDown             matlab.ui.control.DropDown
        DatatypeDropDownLabel        matlab.ui.control.Label
        AddCarButton                 matlab.ui.control.Button
        NextImageButton              matlab.ui.control.Button
        StartButton                  matlab.ui.control.Button
        UIAxes2                      matlab.ui.control.UIAxes
        UIAxes                       matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        DialogApp     % Configuration window
        currentImage  % Name of the current image
        previousImage % Name of the previous image
        data          % Array of strings to be stored when exiting the app
        nameFileNumber = 'lastFrame.txt' %Name of file to store the las frame
        roi  = {}     % Rectangles whose data must be stored
        image0
        image1
        classTypes = {'Car','Truck','Pedestrian','Bicycle','Motorbike','e-Scooter'}
        coloursTypes = {'r','magenta','g','c','b','y'}
        lineWidth = 2
        roiStruct 
        offROI = 1 %5
        marginOffset = 5
        movingCamera = false
        nErosions    = 1
        nDilatations = 1
        automaticOffROI
        visible       % Variable to save how many automatic ROIs have been created for the drop down menus
        delay = 3     % Nº of sample to look back to compare with current one
        saveOnClose = true
        imageHeight   %Height of the current image
        imageWidth    %Width of the current image
        pixelInROI    %Array that for each pixel will have a 0 if not currently in a ROI or a 1 otherwise
    end
    
    methods (Access = private)

        %Function for saving and exiting
        function [] = saveExit(app)
            oldfolder = cd('dataset');
            fileID = fopen("img"+app.currentImage+".txt",'w');
            %Saves up the ROIs of the image
            for i=app.offROI:length(app.roi)
                try
                    if (~isempty(app.roi(i).roi))
                        s = append(app.roi(i).roi.UserData,' ');
                        s = append(s,mat2str((app.roi(i).roi.Position(1)+(app.roi(i).roi.Position(3)/2))/app.imageWidth),' ');
                        s = append(s,mat2str((app.roi(i).roi.Position(2)+(app.roi(i).roi.Position(4)/2))/app.imageHeight),' ');
                        s = append(s, mat2str(app.roi(i).roi.Position(3)/app.imageWidth),' ');
                        s = append(s,mat2str(app.roi(i).roi.Position(4)/app.imageHeight));
                        fprintf(fileID,'%s',s);
                        fprintf(fileID,'\n');
                    end
                catch
                    uialert(app.UIFigure,'A ROI has been deleted','Warning','Icon','info');
                end
            end
            %Update the offROI to the last position of ROIs +1
            app.offROI = length(app.roi)+1;
            fclose(fileID);
            cd(oldfolder);
            fileID = fopen("lastFrame.txt",'w');
            fprintf(fileID,'%u',app.currentImage);
            fclose(fileID);
        end

        %Function that generates a filtered image from the current one and
        %a previous 
        function [image] = filterImage(app,previousImage,currentImage)
            Filtre = fspecial('average',2);
            app.image0 = rgb2ycbcr(previousImage);
            app.image0 = imfilter(app.image0,Filtre,'corr');
            app.image1 = rgb2ycbcr(currentImage);
            app.image1 = imfilter(app.image1,Filtre,'corr');
            image = app.image1(:,:,1)-app.image0(:,:,1);
            image = rescale(image);
            image = imbinarize(image,0.1);
            image = imerode(image,[1 0 1]);
            image = imdilate(image,[1 0 1]);
            image = imdilate(image,[1 0 1]);
            image = imfilter(image,Filtre,'corr');
            image = medfilt2(image);   

            image = imerode(image,[1 0 1]);
            SE = strel('rectangle',[5 4]);
            image = imopen(image,SE);

            %Settings from configuration
            SE = strel('square',3);
            image = imclose(image,SE);

            for i=1:app.nErosions
                image = imerode(image,SE);
            end
            for i=1:app.nDilatations
                image = imdilate(image,SE);
            end
            
        end

        %Actualize the array to save up information from which pixels
        % are already in a ROI
        function []= actualizeDatabase(app,x,y,width,height)
            try
                for i=(x-app.marginOffset):(x+width+app.marginOffset)
                    for j =(y-app.marginOffset):(y+height+app.marginOffset)
                        app.pixelInROI(j,i) = 1;
                    end
                end
            catch
                for i=(x):(x+width)
                    for j =(y):(y+height)
                        app.pixelInROI(j,i) = 1;
                    end
                end
            end
        end

        %This function, given a column, a row and an image, finds the 
        %rectangle around the region of 1s. 
        function [x,y,width,height] = createRectangle(app,row,column,image)
            fix=false;
            fiy=false;
            j=column;
            %Find x(column) of last position
            while ~fix
                if(isempty(find(image(:,j)))==true)
                    fix=true;
                    x=j;
                end
                j = j+1;
                if(j>length(image(1,:)))
                    j = j-1;
                    x = j;
                    fix=true;
                end
            end
            %Find y(row) of last position
            i=row;
            while ~fiy
                if(isempty(find(image(i,column:j)))==true)
                    fiy=true;
                    y=i;
                end
                i = i+1;
                if(i>length(image(:,1)))
                    i = i-1;
                    y = i;
                    fiy=true;
                end
            end
            width  = x-column;
            height = y-row;
            x = column;
            y = row;
            app.actualizeDatabase(x,y,width,height);
        end

        
        function [] = saveData(app,data,x)
            fileID = fopen('trainingData.txt','w');
            fprintf(fileID,x);
            fclose(fileID);
        end

        function[previousImage,currentImage] = moveImage(app,previousImage,currentImage,i,j)
            x = length(previousImage(1,:,:));
            y = length(previousImage(:,1,:));  
            if(i<0)
                if(j<0)
                    previousImage = previousImage(1:y+i,1:x+j,:);
                    currentImage  = currentImage(-i+1:y,-j+1:x,:);
                elseif(j==0)
                    previousImage = previousImage(1:y+i,:,:);
                    currentImage  = currentImage(-i+1:y,:,:);
                else
                    previousImage = previousImage(1:y+i,1+j:x,:);
                    currentImage  = currentImage(-i+1:y,1:x-j,:);
                end
            elseif(i==0)
                %Case when i==0,j==0 is when nothing has to be done
                if(j<0)
                    previousImage = previousImage(:,1:x+j,:);
                    currentImage  = currentImage(:,-j+1:x,:);
                elseif(j>0)
                    previousImage = previousImage(:,1+j:x,:);
                    currentImage  = currentImage(:,1:x-j,:);
                end
            else
                if(j<0)
                    previousImage = previousImage(1+i:y,1:x+j,:);
                    currentImage  = currentImage(1:y-i,1-j:x,:);
                elseif(j==0)
                    previousImage = previousImage(1+i:y,:,:);
                    currentImage  = currentImage(1:y-i,:,:);
                else
                    previousImage = previousImage(1+i:y,1+j:x,:);
                    currentImage  = currentImage(1:y-i,1:x-j,:);
                end
            end
        end

        function [image] = filterMovingImage(app,previousImage,currentImage)
            %Number of columns +/- to make comparisons
            offsetColumns   = 3;
            offsetRows      = 3;
            loss = zeros(2*offsetRows,2*offsetColumns);
            for i=-offsetRows:offsetRows
                for j=-offsetColumns:offsetColumns
                    [previousImage1,currentImage1] = moveImage(app,previousImage,currentImage,i,j);
                    imageB = filterImage(app,previousImage1,currentImage1);
                    long = size(previousImage1(:,1,1));
                    lat = size(previousImage1(1,:,1));
                    loss(i+offsetRows+1,j+offsetColumns+1) = (sum(sum(imageB)));%;/(long(1)*lat(2));
                    lossWeighted(i+offsetRows+1,j+offsetColumns+1) = (sum(sum(imageB)))/(long(1)*lat(2));
                end
            end
            iSmall = 1;
            jSmall = 1;
            lossSmall = loss(1,1);
            for i=1:length(loss(:,1))
                for j=1:length(loss(1,:))
                    if(loss(i,j)<lossSmall)
                        iSmall = i;
                        jSmall = j;
                        lossSmall = loss(i,j);
                    end
                end
            end
            iSmall = iSmall - offsetRows - 1
            jSmall = jSmall - offsetColumns - 1
            [previousImage1,currentImage1] = moveImage(app,previousImage,currentImage,iSmall,jSmall);
            image = filterImage(app,previousImage1,currentImage1);


        end
        
        function [] = generatePicture(app)
            if(app.previousImage~=0)
                try
                    oldfolder = cd('frames');
                    imageA = imread("frame-"+app.previousImage+".jpg");
                    imageB = imread("frame-"+app.currentImage+".jpg");
                    app.imageHeight = length(imageB(:,1,1));
                    app.imageWidth  = length(imageB(1,:,1));
                    app.pixelInROI = zeros(app.imageHeight,app.imageWidth);
                catch
                    cd(oldfolder);
                    YN = uiconfirm(app.UIFigure,'There are no more images, closing down', 'success');
                    if strcmpi(YN,'OK')
                        delete(app)
                    end
                end
                cd(oldfolder);
                imshow(imageB,'Parent',app.UIAxes);
                hold on

                %Changes to accomodate moving drone
                if app.movingCamera==false
                    B = filterImage(app,imageA,imageB);
                else
                    B = filterMovingImage(app,imageA,imageB);
                end
                imshow(B,'Parent',app.UIAxes2); 

                %Function that automatically generates ROIs
                try automaticDetection(app,B); catch; end

                %Ask user if he wants to proceed to the next image
                try
                    YN = uiconfirm(app.UIFigure,'Do you want to proceed to the next image?', '');
                    if strcmpi(YN,'OK')
                        nextImage(app);
                    end
                catch
                end
            else
                oldfolder = cd('frames');
                app.image1 = imread("frame-"+num2str(app.currentImage)+".jpg");
                app.imageHeight = length(app.image1(:,1,1));
                app.imageWidth  = length(app.image1(1,:,1));
                cd(oldfolder);
                imshow(app.image1,'Parent',app.UIAxes);
            end    
        end
                        
        function [] = setROIType(app,type,number)
            index = find(strcmp(app.classTypes,type));
            app.roi(app.offROI+number-1).roi.UserData = int2str(index);
            app.roi(app.offROI+number-1).roi.EdgeColor = char(app.coloursTypes(index));
        end

        function [] = changeROIType(app,type)
            try
                app.roi(app.automaticOffROI).roi.UserData = int2str(type);
                app.roi(app.automaticOffROI).roi.EdgeColor = char(app.coloursTypes(type));
            catch
            end
        end
       
        %Function to change the ROI type, or delete the ROI if inputed by
        % the user
        function [] = identify(app)
            try
                type = getkey();
                type = type - 48;%Conversion from ASCII to decimal
                if(type==0)
                    delete(app.roi(app.automaticOffROI).roi)
                    app.roi(app.automaticOffROI).roi = []
                    app.automaticOffROI = app.automaticOffROI-1;
                elseif(type>=2 && type<=6)
                    changeROIType(app,type)
                end
            catch 
            end
        end
        
        %Function that changes the current image to the next one
        function [] = nextImage(app)
            saveExit(app);
            app.automaticOffROI = app.offROI;
            if(app.previousImage>0)
                app.previousImage = app.previousImage+1;
            elseif(app.previousImage==0 && app.currentImage+1>=app.delay)
                app.previousImage = 1;
            end
            app.currentImage  = app.currentImage+1;
            app.Label.Text = string(app.currentImage);
            generatePicture(app);
        end

        %Function that automatically goes through the filtered image and
        %goes generating ROIs around possible locations of objects
        function [] = automaticDetection(app,image)
            for column=1:length(image(1,:))
                rows = find(image(:,column));
                for j=1:length(rows)
                    if(app.pixelInROI(rows(j),column)==0)
                        [x1,y1,w1,h1] = createRectangle(app,rows(j),column,image);
                        app.roi(app.automaticOffROI).roi = images.roi.Rectangle('Parent', app.UIAxes,'Position',[x1-app.marginOffset,y1-app.marginOffset,w1+app.marginOffset,h1+app.marginOffset],'EdgeColor','r','LabelVisible','hover','LineWidth',2,'UserData','1');
                        identify(app);
                        app.automaticOffROI = app.automaticOffROI+1;
                    end
                end
            end
        end
    end
    
    methods (Access = public)
        function [] = updateConfiguration(app,s,m,sc,mc,dil,ero)
            app.delay  = s;
            app.marginOffset = m;
            if (sc==1) 
                app.saveOnClose = true;
            elseif(sc==0) 
                app.saveOnClose = false;
            else
                 app.saveOnClose = sc;
            end
            if(mc==1)
                app.movingCamera = true;
            elseif(mc==0)
                app.movingCamera = false;
            else
                app.movingCamera = mc;
            end
            app.nDilatations = dil;
            app.nErosions    = ero;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            disableDefaultInteractivity(app.UIAxes);
            mkdir 'dataset' %Creation of folder to store dataset
            if exist(app.nameFileNumber, 'file')
                fileID = fopen(app.nameFileNumber,'r');
                A = fscanf(fileID,'%u');
                if(~isempty(A))
                    app.currentImage = A+1;
                    if(A>app.delay)
                        app.previousImage = A+1-app.delay;
                    else 
                        app.previousImage = 0;
                    end
                else
                    app.currentImage  = 1;
                    app.previousImage = 0;
                end
            else
                fileID = fopen(app.nameFileNumber,'w');
                app.currentImage  = 1;
                app.previousImage = 0;
            end
            fclose(fileID);
            %Load configuration from the config file
            if exist('config.txt','file')
                fileID = fopen('config.txt','r');
                A = fscanf(fileID,'%u');
                if(~isempty(A))
                    app.updateConfiguration(A(1),A(2),A(3),A(4),A(5),A(6))
                end
            else
                % If the config file has not yet been created it is now
                %generated
                fileID = fopen('config.txt','w');
            end
            fclose(fileID);
            app.Label.Text = string(app.currentImage);
            app.automaticOffROI = app.offROI;

            %Remove axis from the UIAxis that will show the images
            axis(app.UIAxes,'off')
            axis(app.UIAxes2,'off')

            drawnow;
            app.UIFigure.WindowState = 'maximized';


        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            generatePicture(app);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            YN = uiconfirm(app.UIFigure,'Do you want to close the app?', 'Close request');
            if strcmpi(YN,'OK')
                if app.saveOnClose
                    saveExit(app);
                end
                %Save up the current configuration at the config file
                if (app.saveOnClose)
                    soc=1;
                else
                    soc=0;
                end
                if(app.movingCamera)
                    amc=1;
                else
                    amc=0;
                end
                fileID = fopen('config.txt','w');
                fprintf(fileID,'%u\n',[app.delay;app.marginOffset;soc;amc;app.nDilatations; app.nErosions]);
                fclose(fileID);
                delete(app)
            end
        end

        % Button pushed function: NextImageButton
        function NextImageButtonPushed(app, event)
            nextImage(app);
        end

        % Button pushed function: AddCarButton
        function AddCarButtonPushed(app, event)
            app.roi(length(app.roi)+1).roi = images.roi.Rectangle('Parent', app.UIAxes,'Position',[240,135,0.2*app.imageHeight,0.2*app.imageWidth],'EdgeColor','r','LineWidth',app.lineWidth,'LabelVisible','inside','UserData','1');
        end

        % Button pushed function: PreviousImageButton
        function PreviousImageButtonPushed(app, event)
            YN = uiconfirm(app.UIFigure,'Are you sure you want to go back to the previous image?', 'Close request');
            if strcmpi(YN,'OK')
                app.previousImage = app.previousImage-1;
                app.currentImage  = app.currentImage-1;
                try
                    app.Label.Text = string(app.currentImage);
                    generatePicture(app);
                catch
                end
            end
        end

        % Button pushed function: AddTruckButton
        function AddTruckButtonPushed(app, event)
            app.roi(length(app.roi)+1).roi = images.roi.Rectangle('Parent', app.UIAxes,'Position',[240,135,30,30],'EdgeColor','magenta','LineWidth',app.lineWidth,'LabelVisible','inside','UserData','2');
        end

        % Button pushed function: AddPedestrianButton
        function AddPedestrianButtonPushed(app, event)
            app.roi(length(app.roi)+1).roi = images.roi.Rectangle('Parent', app.UIAxes,'Position',[240,135,30,30],'EdgeColor','g','LineWidth',app.lineWidth,'LabelVisible','inside','UserData','3');    
        end

        % Button pushed function: AddBicycleButton
        function AddBicycleButtonPushed(app, event)
            app.roi(length(app.roi)+1).roi = images.roi.Rectangle('Parent', app.UIAxes,'Position',[240,135,30,30],'EdgeColor','c','LineWidth',app.lineWidth,'LabelVisible','inside','UserData','4'); 
        end

        % Button pushed function: AddMotorbikeButton
        function AddMotorbikeButtonPushed(app, event)
            app.roi(length(app.roi)+1).roi = images.roi.Rectangle('Parent', app.UIAxes,'Position',[240,135,30,30],'EdgeColor','b','LineWidth',app.lineWidth,'LabelVisible','inside','UserData','5'); 
        end

        % Button pushed function: AddBusButton
        function AddBusButtonPushed(app, event)
            app.roi(length(app.roi)+1).roi = images.roi.Rectangle('Parent', app.UIAxes,'Position',[240,135,30,30],'EdgeColor','y','LineWidth',app.lineWidth,'LabelVisible','hover','UserData','6'); 
        end

        % Callback function
        function DropDownValueChanged(app, event)
            value = app.DropDown.Value;
            setROIType(app,value,1);
        end

        % Image clicked function: Image
        function ImageClicked(app, event)
            app.DialogApp = ConfigurationDialogApp(app, app.delay, app.marginOffset,app.saveOnClose,app.movingCamera,app.nErosions,app.nDilatations);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Image')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.UIAxes.XTickLabel = {'0'; '0.2'; '0.4'; '0.6'; '0.8'; '1'};
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Processed Image')
            zlabel(app.UIAxes2, 'Z')
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.NextImageButton = uibutton(app.UIFigure, 'push');
            app.NextImageButton.ButtonPushedFcn = createCallbackFcn(app, @NextImageButtonPushed, true);
            app.NextImageButton.Icon = 'Next_Arrow.png';
            app.NextImageButton.Text = 'Next Image';
            app.AddCarButton = uibutton(app.UIFigure, 'push');
            app.AddCarButton.ButtonPushedFcn = createCallbackFcn(app, @AddCarButtonPushed, true);
            app.AddCarButton.Text = '1.Add Car';
            app.DatatypeDropDownLabel = uilabel(app.UIFigure);
            app.DatatypeDropDownLabel.Visible = 'off';
            app.DatatypeDropDownLabel.Text = 'Data type';
            app.DatatypeDropDown = uidropdown(app.UIFigure);
            app.DatatypeDropDown.Items = {'YOLO'};
            app.DatatypeDropDown.Visible = 'off';
            app.DatatypeDropDown.Value = 'YOLO';
            app.PreviousImageButton = uibutton(app.UIFigure, 'push');
            app.PreviousImageButton.ButtonPushedFcn = createCallbackFcn(app, @PreviousImageButtonPushed, true);
            app.PreviousImageButton.Icon = 'Previous_Arrow.png';
            app.PreviousImageButton.Text = 'Previous Image';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.Image.ImageSource = 'esaii-barcelonatech.png';
            app.AddPedestrianButton = uibutton(app.UIFigure, 'push');
            app.AddPedestrianButton.ButtonPushedFcn = createCallbackFcn(app, @AddPedestrianButtonPushed, true);
            app.AddPedestrianButton.Text = '3.Add Pedestrian';
            app.AddBicycleButton = uibutton(app.UIFigure, 'push');
            app.AddBicycleButton.ButtonPushedFcn = createCallbackFcn(app, @AddBicycleButtonPushed, true);
            app.AddBicycleButton.Text = '4.Add Bicycle';
            app.AddTruckButton = uibutton(app.UIFigure, 'push');
            app.AddTruckButton.ButtonPushedFcn = createCallbackFcn(app, @AddTruckButtonPushed, true);
            app.AddTruckButton.Text = '2.Add Truck';
            app.AddMotorbikeButton = uibutton(app.UIFigure, 'push');
            app.AddMotorbikeButton.ButtonPushedFcn = createCallbackFcn(app, @AddMotorbikeButtonPushed, true);
            app.AddMotorbikeButton.Text = '5.Add Motorbike';
            app.AddBusButton = uibutton(app.UIFigure, 'push');
            app.AddBusButton.ButtonPushedFcn = createCallbackFcn(app, @AddBusButtonPushed, true);
            app.AddBusButton.Text = '6.Add Bus';
            app.AddRegionofInterestROILabel = uilabel(app.UIFigure);
            app.AddRegionofInterestROILabel.Text = 'Add Region of Interest (ROI)';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.CurrentImageLabel = uilabel(app.UIFigure);
            app.CurrentImageLabel.Text = 'Current Image:';
            app.NothingButton = uibutton(app.UIFigure, 'push');
            app.NothingButton.Text = '0. Nothing';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Computer_Vision_Dataset_Generator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end