classdef Computer_Vision_Dataset_Validator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        Label_2                      matlab.ui.control.Label
        CurrentFolderLabel           matlab.ui.control.Label
        CurrentImageLabel            matlab.ui.control.Label
        Label                        matlab.ui.control.Label
        AddRegionofInterestROILabel  matlab.ui.control.Label
        AddBusButton                 matlab.ui.control.Button
        AddMotorbikeButton           matlab.ui.control.Button
        AddTruckButton               matlab.ui.control.Button
        AddBicycleButton             matlab.ui.control.Button
        AddPedestrianButton          matlab.ui.control.Button
        Image                        matlab.ui.control.Image
        AddCarButton                 matlab.ui.control.Button
        NextImageButton              matlab.ui.control.Button
        StartButton                  matlab.ui.control.Button
        UIAxes                       matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        DialogApp     % Configuration window
        currentImage  % Name of the current image
        previousImage % Name of the previous image
        data          % Array of strings to be stored when exiting the app
        nameFileNumber = 'lastFrame.txt' %Name of file to store the las frame
        roi  = {}     % Rectangles whose data must be stored
        image0
        image1
        classTypes = {'Car','Truck','Pedestrian','Bicycle','Motorbike','e-Scooter'}
        coloursTypes = {'r','magenta','g','c','b','y'}
        lineWidth = 2
        roiStruct 
        offROI = 1 %5
        marginOffset = 5
        movingCamera = false
        nErosions    = 1
        nDilatations = 1
        automaticOffROI
        visible       % Variable to save how many automatic ROIs have been created for the drop down menus
        delay = 3     % Nº of sample to look back to compare with current one
        saveOnClose = true
        imageHeight   %Height of the current image
        imageWidth    %Width of the current image
        pixelInROI    %Array that for each pixel will have a 0 if not currently in a ROI or a 1 otherwise
        imageValidate
        reduction = 1 %Factor to reduce Validation Dataset, if 2 by half (to get correct fps)
        validateFrame %Number of frame of validation(1); index of folder of the last frame(2)
    end
    
    methods (Access = private)

        %Function for saving and exiting
        function [] = saveExit(app)
            oldfolder = cd('datasetVALIDATED');
            currentfolder = cd('Images');
            A = length(dir)-2+1;
            B = append(string(A),'.jpg');
            C = append(string(A),'.txt');
            try
                imwrite(app.imageValidate,B);
            catch
            end
            cd(currentfolder);
            cd('labels');
            fileID = fopen(C,'w');
            %Saves up the ROIs of the image
            for i=app.offROI:length(app.roi)
                try
                    if (~isempty(app.roi(i).roi))
                        s = append(app.roi(i).roi.UserData,' ');
                        s = append(s,mat2str((app.roi(i).roi.Position(1)+(app.roi(i).roi.Position(3)/2))/app.imageWidth),' ');
                        s = append(s,mat2str((app.roi(i).roi.Position(2)+(app.roi(i).roi.Position(4)/2))/app.imageHeight),' ');
                        s = append(s,mat2str(app.roi(i).roi.Position(3)/app.imageWidth),' ');
                        s = append(s,mat2str(app.roi(i).roi.Position(4)/app.imageHeight));
                        fprintf(fileID,'%s',s);
                        fprintf(fileID,'\n');
                    end
                catch
                    %uialert(app.UIFigure,'A ROI has been deleted','Warning','Icon','info');
                end
            end
            %Update the offROI to the last position of ROIs +1
            app.offROI = length(app.roi)+1;
            fclose(fileID);
            cd(oldfolder);
            %fileID = fopen("lastFrame.txt",'w');
            %fprintf(fileID,'%u',app.currentImage);
            %fclose(fileID);

            
        end

        %Function that generates a filtered image from the current one and
        %a previous 
        function [image] = filterImage(app,previousImage,currentImage)
            Filtre = fspecial('average',2);
            app.image0 = rgb2ycbcr(previousImage);
            app.image0 = imfilter(app.image0,Filtre,'corr');
            app.image1 = rgb2ycbcr(currentImage);
            app.image1 = imfilter(app.image1,Filtre,'corr');
            image = app.image1(:,:,1)-app.image0(:,:,1);
            image = rescale(image);
            image = imbinarize(image,0.1);
            image = imerode(image,[1 0 1]);
            image = imdilate(image,[1 0 1]);
            image = imdilate(image,[1 0 1]);
            image = imfilter(image,Filtre,'corr');
            image = medfilt2(image);   

            %Prova
            image = imerode(image,[1 0 1]);
            SE = strel('rectangle',[5 4]);
            image = imopen(image,SE);

            %Settings from configuration
            SE = strel('square',3);
            image = imclose(image,SE);

            for i=1:app.nErosions
                image = imerode(image,SE);
            end
            for i=1:app.nDilatations
                image = imdilate(image,SE);
            end
            
        end

        %Actualize the array to save up information from which pixels
        % are already in a ROI
        function []= actualizeDatabase(app,x,y,width,height)
            try
                for i=(x-app.marginOffset):(x+width+app.marginOffset)
                    for j =(y-app.marginOffset):(y+height+app.marginOffset)
                        app.pixelInROI(j,i) = 1;
                    end
                end
            catch
                for i=(x):(x+width)
                    for j =(y):(y+height)
                        app.pixelInROI(j,i) = 1;
                    end
                end
            end
        end

        %This function, given a column, a row and an image, finds the 
        %rectangle around the region of 1s. 
        function [x,y,width,height] = createRectangle(app,row,column,image)
            fix=false;
            fiy=false;
            j=column;
            %Find x(column) of last position
            while ~fix
                if(isempty(find(image(:,j)))==true)
                    fix=true;
                    x=j;
                end
                j = j+1;
                if(j>length(image(1,:)))
                    j = j-1;
                    x = j;
                    fix=true;
                end
            end
            %Find y(row) of last position
            i=row;
            while ~fiy
                if(isempty(find(image(i,column:j)))==true)
                    fiy=true;
                    y=i;
                end
                i = i+1;
                if(i>length(image(:,1)))
                    i = i-1;
                    y = i;
                    fiy=true;
                end
            end
            width  = x-column;
            height = y-row;
            x = column;
            y = row;
            app.actualizeDatabase(x,y,width,height);
        end
        
        function [] = setROIType(app,type,number)
            index = find(strcmp(app.classTypes,type));
            app.roi(app.offROI+number-1).roi.UserData = int2str(index);
            app.roi(app.offROI+number-1).roi.EdgeColor = char(app.coloursTypes(index));
        end

        function [] = changeROIType(app,type)
            try
                app.roi(app.automaticOffROI).roi.UserData = int2str(type);
                app.roi(app.automaticOffROI).roi.EdgeColor = char(app.coloursTypes(type));
            catch
            end
        end
       
        %Function to change the ROI type, or delete the ROI if inputed by
        % the user
        function [] = identify(app)
            try
                type = getkey();
                type = type - 48;%Conversion from ASCII to decimal
                if(type==0)
                    delete(app.roi(app.automaticOffROI).roi)
                    app.roi(app.automaticOffROI).roi = []
                    app.automaticOffROI = app.automaticOffROI-1;
                elseif(type>=2 && type<=6)
                    changeROIType(app,type)
                end
            catch 
            end
        end
        
        %Function that changes the current image to the next one
        function [] = nextImage(app)
            saveExit(app);
            app.automaticOffROI = app.offROI;
            %if(app.previousImage>0)
            %    app.previousImage = app.previousImage+1;
            %elseif(app.previousImage==0 && app.currentImage+1>=app.delay)
            %    app.previousImage = 1;
            %end
            %app.currentImage  = app.currentImage+1;
            app.Label.Text = string(app.validateFrame(1));
            app.Label_2.Text = string(app.validateFrame(2));
            %generatePicture(app);
            validateDataset(app);
        end

        %Function that automatically goes through the filtered image and
        %goes generating ROIs around possible locations of objects
        function [] = automaticDetection(app,image)
            for column=1:length(image(1,:))
                rows = find(image(:,column));
                for j=1:length(rows)
                    if(app.pixelInROI(rows(j),column)==0)
                        [x1,y1,w1,h1] = createRectangle(app,rows(j),column,image);
                        app.roi(app.automaticOffROI).roi = images.roi.Rectangle('Parent', app.UIAxes,'Position',[x1-app.marginOffset,y1-app.marginOffset,w1+app.marginOffset,h1+app.marginOffset],'EdgeColor','r','LabelVisible','hover','LineWidth',2,'UserData','1');
                        identify(app);
                        app.automaticOffROI = app.automaticOffROI+1;
                    end
                end
            end
        end
    end
    
    methods (Access = public)
        function [] = updateConfiguration(app,s,m,sc,mc,dil,ero)
            app.delay  = s;
            app.marginOffset = m;
            if (sc==1) 
                app.saveOnClose = true;
            elseif(sc==0) 
                app.saveOnClose = false;
            else
                 app.saveOnClose = sc;
            end
            if(mc==1)
                app.movingCamera = true;
            elseif(mc==0)
                app.movingCamera = false;
            else
                app.movingCamera = mc;
            end
            app.nDilatations = dil;
            app.nErosions    = ero;
        end

        function zerosInitial = getZeros(app)
            if (app.validateFrame(1)*app.reduction<10)
                zerosInitial = "000000";
            elseif(app.validateFrame(1)*app.reduction<100)
                zerosInitial = "00000";
            elseif(app.validateFrame(1)*app.reduction<1000)
                zerosInitial = "0000";
            elseif(app.validateFrame(1)*app.reduction<10000)
                zerosInitial = "000";
            end
        end
        
        function [] = validateDataset(app)
            oldfolder = cd('validateDataset');
            currentfolder = cd('frames');
            A = dir;
            try
                %Open folder
                nameCurrent  = A(2+app.validateFrame(2)).('name');
                cd(nameCurrent);
            catch
                %No more images
                YN = uiconfirm(app.UIFigure,'There are no more images, closing down', 'success');
                if strcmpi(YN,'OK')
                    delete(app)
                end
            end

            zerosInitial = getZeros(app);

            try
                %Open current image
                number = app.validateFrame(1)*app.reduction;
                app.imageValidate = imread(zerosInitial+number+".jpg");
                app.imageHeight = length(app.imageValidate(:,1,1));
                app.imageWidth  = length(app.imageValidate(1,:,1));
                imshow(app.imageValidate,'Parent',app.UIAxes);
                dimension = length(dir);
            catch
                %Case where there are no images in the folder
                app.validateFrame(2) = app.validateFrame(2)+1;
                app.validateFrame(1) = 1;
            end

            cd(currentfolder);
            cd('annotations');
            Datafile = table2array(readtable(append(nameCurrent,'.txt')));
            limit = size(Datafile);
            for i=1:limit(1)
                if(Datafile(i,1)==app.validateFrame(1)*app.reduction && Datafile(i,8) ~= 0 && Datafile(i,8) ~= 11 && Datafile(i,10) ~= 2)
                    typeObject = Datafile(i,8);
                    if(typeObject==1 || typeObject==2)
                        typeObject='3';
                        colorObject = 'g';
                    elseif(typeObject==3)  
                        typeObject='4';
                        colorObject = 'c';
                    elseif(typeObject==4 || typeObject==5 || typeObject==11)
                        typeObject='1';
                        colorObject = 'r';
                    elseif(typeObject==6)
                        typeObject='2';
                        colorObject = 'magenta';
                    elseif(typeObject==9)
                        typeObject='6';
                        colorObject = 'y';
                    elseif(typeObject==7 || typeObject==8 || typeObject == 10) 
                        typeObject='5';
                        colorObject = 'b';
                    end
                    app.roi(length(app.roi)+1).roi = images.roi.Rectangle('Parent', app.UIAxes,'Position',[Datafile(i,3),Datafile(i,4),Datafile(i,5),Datafile(i,6)],'EdgeColor',colorObject,'LineWidth',app.lineWidth,'LabelVisible','inside','UserData',typeObject);
                end
            end
            if((dimension-2)>=((app.validateFrame(1)+1)*app.reduction))
                %There are still more images in this folder
                app.validateFrame(1) = app.validateFrame(1) +1;
            else
                %No more images in this folder
                app.validateFrame(2) = app.validateFrame(2)+1;
                app.validateFrame(1) = 1;
            end
            cd(oldfolder);
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            disableDefaultInteractivity(app.UIAxes);
            if(~exist('datasetVALIDATED','file'))
                mkdir 'datasetVALIDATED'; %Creation of folder to store dataset
                currentfolder = cd('datasetVALIDATED');
                mkdir 'Images';
                mkdir 'labels';
                cd(currentfolder);
            end
            if exist(app.nameFileNumber, 'file')
                fileID = fopen(app.nameFileNumber,'r');
                A = fscanf(fileID,'%u');
                if(~isempty(A))
                    app.currentImage = A+1;
                    if(A>app.delay)
                        app.previousImage = A+1-app.delay;
                    else 
                        app.previousImage = 0;
                    end
                else
                    app.currentImage  = 1;
                    app.previousImage = 0;
                end
            else
                fileID = fopen(app.nameFileNumber,'w');
                app.currentImage  = 1;
                app.previousImage = 0;
            end
            fclose(fileID);
            %Load configuration from the config file
            if exist('config.txt','file')
                fileID = fopen('config.txt','r');
                A = fscanf(fileID,'%u');
                if(~isempty(A))
                    app.updateConfiguration(A(1),A(2),A(3),A(4),A(5),A(6));
                end
            else
                % If the config file has not yet been created it is now
                %generated
                fileID = fopen('config.txt','w');
            end
            fclose(fileID);

            if exist('lastValidateFrame.txt','file')
                fileID = fopen('lastValidateFrame.txt','r');
                A = fscanf(fileID,'%u');
                if(~isempty(A))
                    app.validateFrame(1) = A(1);
                    app.validateFrame(2) = A(2);
                else
                    app.validateFrame(1)  = 1;
                    app.validateFrame(2) =  1;
                end
            else
                fileID = fopen('lastValidateFrame.txt','w');
                app.validateFrame(1) = 1;
                app.validateFrame(2) = 1;
            end
            fclose(fileID);
            app.Label.Text = string(app.currentImage);
            app.automaticOffROI = app.offROI;

            %Remove axis from the UIAxis that will show the images
            axis(app.UIAxes,'off')

            drawnow;
            app.UIFigure.WindowState = 'maximized';

            app.Label.Text = string(app.validateFrame(1));
            app.Label_2.Text = string(app.validateFrame(2));

        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            %generatePicture(app);
            validateDataset(app);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            YN = uiconfirm(app.UIFigure,'Do you want to close the app?', 'Close request');
            if strcmpi(YN,'OK')
                if app.saveOnClose
                    saveExit(app);
                end
                %Save up the current configuration at the config file
                if (app.saveOnClose)
                    soc=1;
                else
                    soc=0;
                end
                if(app.movingCamera)
                    amc=1;
                else
                    amc=0;
                end
                fileID = fopen('config.txt','w');
                fprintf(fileID,'%u\n',[app.delay;app.marginOffset;soc;amc;app.nDilatations; app.nErosions]);
                fclose(fileID);
                
                %Save up the last frame and folder of the validation
                fileID = fopen('lastValidateFrame.txt','w');
                fprintf(fileID,'%u\n',[app.validateFrame(1),app.validateFrame(2)]);
                fclose(fileID);

                delete(app)
            end
        end

        % Button pushed function: NextImageButton
        function NextImageButtonPushed(app, event)
            nextImage(app);
        end

        % Button pushed function: AddCarButton
        function AddCarButtonPushed(app, event)
            app.roi(length(app.roi)+1).roi = images.roi.Rectangle('Parent', app.UIAxes,'Position',[app.imageWidth/2,app.imageHeight/2,app.imageWidth*0.03,app.imageHeight*0.05],'EdgeColor','r','LineWidth',app.lineWidth,'LabelVisible','inside','UserData','1');
        end

        % Callback function
        function PreviousImageButtonPushed(app, event)
            YN = uiconfirm(app.UIFigure,'Are you sure you want to go back to the previous image?', 'Close request');
            if strcmpi(YN,'OK')
                app.previousImage = app.previousImage-1;
                app.currentImage  = app.currentImage-1;
                try
                    app.Label.Text = string(app.currentImage);
                    generatePicture(app);
                catch
                end
            end
        end

        % Button pushed function: AddTruckButton
        function AddTruckButtonPushed(app, event)
            app.roi(length(app.roi)+1).roi = images.roi.Rectangle('Parent', app.UIAxes,'Position',[app.imageWidth/2,app.imageHeight/2,app.imageWidth*0.03,app.imageHeight*0.05],'EdgeColor','magenta','LineWidth',app.lineWidth,'LabelVisible','inside','UserData','2');
        end

        % Button pushed function: AddPedestrianButton
        function AddPedestrianButtonPushed(app, event)
            app.roi(length(app.roi)+1).roi = images.roi.Rectangle('Parent', app.UIAxes,'Position',[app.imageWidth/2,app.imageHeight/2,app.imageWidth*0.03,app.imageHeight*0.05],'EdgeColor','g','LineWidth',app.lineWidth,'LabelVisible','inside','UserData','3');    
        end

        % Button pushed function: AddBicycleButton
        function AddBicycleButtonPushed(app, event)
            app.roi(length(app.roi)+1).roi = images.roi.Rectangle('Parent', app.UIAxes,'Position',[app.imageWidth/2,app.imageHeight/2,app.imageWidth*0.03,app.imageHeight*0.05],'EdgeColor','c','LineWidth',app.lineWidth,'LabelVisible','inside','UserData','4'); 
        end

        % Button pushed function: AddMotorbikeButton
        function AddMotorbikeButtonPushed(app, event)
            app.roi(length(app.roi)+1).roi = images.roi.Rectangle('Parent', app.UIAxes,'Position',[app.imageWidth/2,app.imageHeight/2,app.imageWidth*0.03,app.imageHeight*0.05],'EdgeColor','b','LineWidth',app.lineWidth,'LabelVisible','inside','UserData','5'); 
        end

        % Button pushed function: AddBusButton
        function AddBusButtonPushed(app, event)
            app.roi(length(app.roi)+1).roi = images.roi.Rectangle('Parent', app.UIAxes,'Position',[app.imageWidth/2,app.imageHeight/2,app.imageWidth*0.03,app.imageHeight*0.05],'EdgeColor','y','LineWidth',app.lineWidth,'LabelVisible','hover','UserData','6'); 
        end

        % Callback function
        function DropDownValueChanged(app, event)
            value = app.DropDown.Value;
            setROIType(app,value,1);
        end

        % Image clicked function: Image
        function ImageClicked(app, event)
            app.DialogApp = ConfigurationDialogApp(app, app.delay, app.marginOffset,app.saveOnClose,app.movingCamera,app.nErosions,app.nDilatations);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Image')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.UIAxes.XTickLabel = {'0'; '0.2'; '0.4'; '0.6'; '0.8'; '1'};
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.NextImageButton = uibutton(app.UIFigure, 'push');
            app.NextImageButton.ButtonPushedFcn = createCallbackFcn(app, @NextImageButtonPushed, true);
            app.NextImageButton.Icon = 'Next_Arrow.png';
            app.NextImageButton.Text = 'Next Image';
            app.AddCarButton = uibutton(app.UIFigure, 'push');
            app.AddCarButton.ButtonPushedFcn = createCallbackFcn(app, @AddCarButtonPushed, true);
            app.AddCarButton.Text = '1.Add Car';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.Image.ImageSource = 'esaii-barcelonatech.png';
            app.AddPedestrianButton = uibutton(app.UIFigure, 'push');
            app.AddPedestrianButton.ButtonPushedFcn = createCallbackFcn(app, @AddPedestrianButtonPushed, true);
            app.AddPedestrianButton.Text = '3.Add Pedestrian';
            app.AddBicycleButton = uibutton(app.UIFigure, 'push');
            app.AddBicycleButton.ButtonPushedFcn = createCallbackFcn(app, @AddBicycleButtonPushed, true);
            app.AddBicycleButton.Text = '4.Add Bicycle';
            app.AddTruckButton = uibutton(app.UIFigure, 'push');
            app.AddTruckButton.ButtonPushedFcn = createCallbackFcn(app, @AddTruckButtonPushed, true);
            app.AddTruckButton.Text = '2.Add Truck';
            app.AddMotorbikeButton = uibutton(app.UIFigure, 'push');
            app.AddMotorbikeButton.ButtonPushedFcn = createCallbackFcn(app, @AddMotorbikeButtonPushed, true);
            app.AddMotorbikeButton.Text = '5.Add Motorbike';
            app.AddBusButton = uibutton(app.UIFigure, 'push');
            app.AddBusButton.ButtonPushedFcn = createCallbackFcn(app, @AddBusButtonPushed, true);
            app.AddBusButton.Text = '6.Add Bus';
            app.AddRegionofInterestROILabel = uilabel(app.UIFigure);
            app.AddRegionofInterestROILabel.Text = 'Add Region of Interest (ROI)';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.CurrentImageLabel = uilabel(app.UIFigure);
            app.CurrentImageLabel.Text = 'Current Image:';
            app.CurrentFolderLabel = uilabel(app.UIFigure);
            app.CurrentFolderLabel.Text = 'Current Folder:';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Computer_Vision_Dataset_Validator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end