classdef ConfigurationDialogApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        NoferosionsEditField          matlab.ui.control.NumericEditField
        NoferosionsEditFieldLabel     matlab.ui.control.Label
        NofdilatationsEditField       matlab.ui.control.NumericEditField
        NofdilatationsEditFieldLabel  matlab.ui.control.Label
        MovingcameraCheckBox          matlab.ui.control.CheckBox
        ConfigurationLabel            matlab.ui.control.Label
        SaveoncloseCheckBox           matlab.ui.control.CheckBox
        margin                        matlab.ui.control.NumericEditField
        NofimagesoffsetLabel_2        matlab.ui.control.Label
        Button                        matlab.ui.control.Button
        EditField                     matlab.ui.control.NumericEditField
        NofimagesoffsetLabel          matlab.ui.control.Label
    end


    properties (Access = private) 
        CallingApp   % Main app object
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function StartupFcn(app, mainapp, sz, m, sc, mc, ero, dil)
            % Store main app in property for CloseRequestFcn to use
            app.CallingApp = mainapp;
            
            % Update UI with input values
            app.EditField.Value = sz;
            app.margin.Value = m;
            app.SaveoncloseCheckBox.Value = sc;
            app.MovingcameraCheckBox.Value = mc;
            app.NoferosionsEditField.Value = ero;
            app.NofdilatationsEditField.Value = dil;
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            % Call main app's public function
            updateConfiguration(app.CallingApp, app.EditField.Value, app.margin.Value,app.SaveoncloseCheckBox.Value,app.MovingcameraCheckBox.Value,app.NofdilatationsEditField.Value,app.NoferosionsEditField.Value);
            
            % Delete the dialog box
            delete(app)
        end

        % Close request function: UIFigure
        function DialogAppCloseRequest(app, event)

            % Delete the dialog box 
            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Options';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @DialogAppCloseRequest, true);
            app.NofimagesoffsetLabel = uilabel(app.UIFigure);
            app.NofimagesoffsetLabel.Text = 'Nº of images offset';
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.EditField.Limits = [1 1000];
            app.EditField.Value = 3;
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = 'Apply';
            app.NofimagesoffsetLabel_2 = uilabel(app.UIFigure);
            app.NofimagesoffsetLabel_2.Text = 'Margin';
            app.margin = uieditfield(app.UIFigure, 'numeric');
            app.margin.Limits = [2 1000];
            app.margin.Value = 5;
            app.SaveoncloseCheckBox = uicheckbox(app.UIFigure);
            app.SaveoncloseCheckBox.Text = 'Save on close';
            app.ConfigurationLabel = uilabel(app.UIFigure);
            app.ConfigurationLabel.Text = 'Configuration';
            app.MovingcameraCheckBox = uicheckbox(app.UIFigure);
            app.MovingcameraCheckBox.Text = 'Moving camera';
            app.NofdilatationsEditFieldLabel = uilabel(app.UIFigure);
            app.NofdilatationsEditFieldLabel.Text = 'Nº of dilatations';
            app.NofdilatationsEditField = uieditfield(app.UIFigure, 'numeric');
            app.NoferosionsEditFieldLabel = uilabel(app.UIFigure);
            app.NoferosionsEditFieldLabel.Text = 'Nº of erosions';
            app.NoferosionsEditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ConfigurationDialogApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)StartupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end